classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        BacktoMainMenu                  matlab.ui.container.Menu
        Image6                          matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        Root3EditField                  matlab.ui.control.NumericEditField
        Root3EditFieldLabel             matlab.ui.control.Label
        Root2EditField                  matlab.ui.control.NumericEditField
        Root2EditFieldLabel             matlab.ui.control.Label
        Root1EditField                  matlab.ui.control.NumericEditField
        Root1EditFieldLabel             matlab.ui.control.Label
        RootfinderLabel                 matlab.ui.control.Label
        freetermEditField               matlab.ui.control.NumericEditField
        freetermEditFieldLabel          matlab.ui.control.Label
        thirdcoefficientEditField       matlab.ui.control.NumericEditField
        thirdcoefficientEditFieldLabel  matlab.ui.control.Label
        secondcoefficientEditField      matlab.ui.control.NumericEditField
        secondcoefficientEditFieldLabel  matlab.ui.control.Label
        firstcoefficientEditField       matlab.ui.control.NumericEditField
        firstcoefficientEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)


    a = app.firstcoefficientEditField.Value;
    b = app.secondcoefficientEditField.Value;
    c = app.thirdcoefficientEditField.Value;
    d = app.freetermEditField.Value;


    equationCoefficients = [a, b, c, -d];
    rootsArray = roots(equationCoefficients);


    if numel(rootsArray) >= 1
        app.Root1EditField.Value = rootsArray(1);
    else
        app.Root1EditField.Value = 0;
    end

    if numel(rootsArray) >= 2
        app.Root2EditField.Value = rootsArray(2);
    else
        app.Root2EditField.Value = 0;
    end

    if numel(rootsArray) >= 3
        app.Root3EditField.Value = rootsArray(3);
    else
        app.Root3EditField.Value = 0;
    end

        end

        % Menu selected function: BacktoMainMenu
        function BacktoMainMenuSelected(app, event)
            closereq;
            app2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.BacktoMainMenu = uimenu(app.Menu);
            app.BacktoMainMenu.MenuSelectedFcn = createCallbackFcn(app, @BacktoMainMenuSelected, true);
            app.BacktoMainMenu.Text = 'Back to Main Menu';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.firstcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.firstcoefficientEditFieldLabel.Text = 'first coefficient';
            app.firstcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.secondcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.secondcoefficientEditFieldLabel.Text = 'second coefficient';
            app.secondcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.thirdcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.thirdcoefficientEditFieldLabel.Text = 'third coefficient';
            app.thirdcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.freetermEditFieldLabel = uilabel(app.UIFigure);
            app.freetermEditFieldLabel.Text = 'free term';
            app.freetermEditField = uieditfield(app.UIFigure, 'numeric');
            app.RootfinderLabel = uilabel(app.UIFigure);
            app.RootfinderLabel.Text = 'Root finder';
            app.Root1EditFieldLabel = uilabel(app.UIFigure);
            app.Root1EditFieldLabel.Text = 'Root 1';
            app.Root1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Root2EditFieldLabel = uilabel(app.UIFigure);
            app.Root2EditFieldLabel.Text = 'Root 2';
            app.Root2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Root3EditFieldLabel = uilabel(app.UIFigure);
            app.Root3EditFieldLabel.Text = 'Root 3';
            app.Root3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'klipartz.com.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'klipartz2.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'klipartz4.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'klipartz3.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Butterfly-PNG-1.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end