classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        RootfinderMenu               matlab.ui.container.Menu
        DPLOTMenu                    matlab.ui.container.Menu
        MOREBUTTERFLIESMenu          matlab.ui.container.Menu
        lessbutterfliesMenu          matlab.ui.container.Menu
        Image5                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        SeedocumentationButton       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        PlotButton_2                 matlab.ui.control.Button
        freetermEditField            matlab.ui.control.NumericEditField
        freetermEditFieldLabel       matlab.ui.control.Label
        rdcoefficientEditField       matlab.ui.control.NumericEditField
        rdcoefficientEditFieldLabel  matlab.ui.control.Label
        ndcoefficientEditField       matlab.ui.control.NumericEditField
        ndcoefficientEditFieldLabel  matlab.ui.control.Label
        stcoefficientEditField       matlab.ui.control.NumericEditField
        stcoefficientEditFieldLabel  matlab.ui.control.Label
        ThirddegreeequationgeneratorLabel  matlab.ui.control.Label
        SignalGeneratorLabel         matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        PhaseEditField               matlab.ui.control.NumericEditField
        PhaseEditFieldLabel          matlab.ui.control.Label
        FrequencyEditField           matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel      matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
    x = app.AmplitudeEditField.Value;
    y = app.FrequencyEditField.Value;
    z = app.PhaseEditField.Value;

    t = linspace(0, 2 * pi, 1000);
    signal = x * sin(y * t + z);


    cla(app.UIAxes);


    plot(app.UIAxes, t, signal);
    
    title(app.UIAxes, 'Generated Signal');
    xlabel(app.UIAxes, 'Time');
    ylabel(app.UIAxes, 'Amplitude');

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)


    a = app.stcoefficientEditField.Value;
    b = app.ndcoefficientEditField.Value;
    c = app.rdcoefficientEditField.Value;
    d = app.freetermEditField.Value;

   
    x = linspace(-10, 10, 100); 

  
    wave = a*x.^3 + b*x.^2 + c*x + d;

  
    cla(app.UIAxes2);

   
    plot(app.UIAxes2, x, wave, 'LineWidth', 2);

  
    title(app.UIAxes2, 'Graph of The Equation');
    xlabel(app.UIAxes2, 'Abscissa');
    ylabel(app.UIAxes2, 'Ordinate');

    
    xlim(app.UIAxes2, [-10, 10]);
    ylim(app.UIAxes2, [-100, 100]);


        end

        % Menu selected function: DPLOTMenu
        function DPLOTMenuSelected(app, event)
           closereq;
           app3

        end

        % Menu selected function: MOREBUTTERFLIESMenu
        function MOREBUTTERFLIESMenuSelected(app, event)
            closereq;
            app4
        end

        % Menu selected function: lessbutterfliesMenu
        function lessbutterfliesMenuSelected(app, event)
            closereq;
            app5
        end

        % Menu selected function: RootfinderMenu
        function RootfinderMenuSelected(app, event)
            closereq;
            app1
        end

        % Button pushed function: SeedocumentationButton
        function SeedocumentationButtonPushed(app, event)
                % Prompt the user to select a PDF file
pdfFilePath = 'C:\Users\Lenovo\Desktop\proiect cag\Documentation_Chifa_Daniel_Kewin.pdf';

    % Check if the file exists
    if exist(pdfFilePath, 'file') == 2
        % Open the PDF file using the system default viewer
        open(pdfFilePath);
    else
        % File does not exist
        disp('Error: PDF file not found.');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.RootfinderMenu = uimenu(app.Menu);
            app.RootfinderMenu.MenuSelectedFcn = createCallbackFcn(app, @RootfinderMenuSelected, true);
            app.RootfinderMenu.Text = 'Root finder';
            app.DPLOTMenu = uimenu(app.Menu);
            app.DPLOTMenu.MenuSelectedFcn = createCallbackFcn(app, @DPLOTMenuSelected, true);
            app.DPLOTMenu.Text = '3D PLOT ';
            app.MOREBUTTERFLIESMenu = uimenu(app.Menu);
            app.MOREBUTTERFLIESMenu.MenuSelectedFcn = createCallbackFcn(app, @MOREBUTTERFLIESMenuSelected, true);
            app.MOREBUTTERFLIESMenu.Text = 'MORE BUTTERFLIES';
            app.lessbutterfliesMenu = uimenu(app.Menu);
            app.lessbutterfliesMenu.MenuSelectedFcn = createCallbackFcn(app, @lessbutterfliesMenuSelected, true);
            app.lessbutterfliesMenu.Text = 'less butterflies :(';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalGeneratorLabel = uilabel(app.UIFigure);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.ThirddegreeequationgeneratorLabel = uilabel(app.UIFigure);
            app.ThirddegreeequationgeneratorLabel.Text = 'Third degree equation generator';
            app.stcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.stcoefficientEditFieldLabel.Text = '1st coefficient';
            app.stcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.ndcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.ndcoefficientEditFieldLabel.Text = '2nd coefficient';
            app.ndcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.rdcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.rdcoefficientEditFieldLabel.Text = '3rd coefficient';
            app.rdcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.freetermEditFieldLabel = uilabel(app.UIFigure);
            app.freetermEditFieldLabel.Text = 'free term';
            app.freetermEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton_2 = uibutton(app.UIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '—Pngtree—picture of blue dreamy glowing_9071029.png');
            app.SeedocumentationButton = uibutton(app.UIFigure, 'push');
            app.SeedocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @SeedocumentationButtonPushed, true);
            app.SeedocumentationButton.Text = 'See documentation';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'klipartz.com.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'klipartz2.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Flying-Blue-Butterflies-PNG-Download-Image.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Flying-Blue-Butterflies-PNG-Download-Image.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end