classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        BacktomainMenu                  matlab.ui.container.Menu
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        rdOrderEquation3DRepresentationLabel  matlab.ui.control.Label
        freetermEditField               matlab.ui.control.NumericEditField
        freetermEditFieldLabel          matlab.ui.control.Label
        thirdcoefficientEditField       matlab.ui.control.NumericEditField
        thirdcoefficientEditFieldLabel  matlab.ui.control.Label
        secondcoefficientEditField      matlab.ui.control.NumericEditField
        secondcoefficientEditFieldLabel  matlab.ui.control.Label
        firstcoefficientEditField       matlab.ui.control.NumericEditField
        firstcoefficientEditFieldLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: BacktomainMenu
        function BacktomainMenuSelected(app, event)
            closereq;
            app2
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
          
    a = app.firstcoefficientEditField.Value;
    b = app.secondcoefficientEditField.Value;
    c = app.thirdcoefficientEditField.Value;
    d = app.freetermEditField.Value;

    x = linspace(-10, 10, 100);
    y = linspace(-10, 10, 100);

    [X, Y] = meshgrid(x, y);

    Z = a*X.^3 + b*X.^2 + c*X + d*Y;


    cla(app.UIAxes);

    surf(app.UIAxes, X, Y, Z, 'EdgeColor', 'none');
    title(app.UIAxes, '3D Plot of Cubic Equation');
    xlabel(app.UIAxes, 'X-axis');
    ylabel(app.UIAxes, 'Y-axis');
    zlabel(app.UIAxes, 'Z-axis');
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.BacktomainMenu = uimenu(app.Menu);
            app.BacktomainMenu.MenuSelectedFcn = createCallbackFcn(app, @BacktomainMenuSelected, true);
            app.BacktomainMenu.Text = 'Back to main';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.firstcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.firstcoefficientEditFieldLabel.Text = 'first coefficient';
            app.firstcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.secondcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.secondcoefficientEditFieldLabel.Text = 'second coefficient';
            app.secondcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.thirdcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.thirdcoefficientEditFieldLabel.Text = 'third coefficient';
            app.thirdcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.freetermEditFieldLabel = uilabel(app.UIFigure);
            app.freetermEditFieldLabel.Text = 'free term';
            app.freetermEditField = uieditfield(app.UIFigure, 'numeric');
            app.rdOrderEquation3DRepresentationLabel = uilabel(app.UIFigure);
            app.rdOrderEquation3DRepresentationLabel.Text = '3rd Order Equation 3D Representation';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pngwing.com.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '—Pngtree—picture of blue dreamy glowing_9071029.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end